classdef MotView < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MotViewUIFigure                 matlab.ui.Figure
        LoadAnimationButton             matlab.ui.control.Button
        Lamp                            matlab.ui.control.Lamp
        PauseButton                     matlab.ui.control.StateButton
        PlayForwardButton               matlab.ui.control.Button
        FrameForwardButton              matlab.ui.control.Button
        PlayBackwardButton              matlab.ui.control.Button
        FrameBackwardButton             matlab.ui.control.Button
        FrameSlider                     matlab.ui.control.Slider
        FrameEditFieldLabel             matlab.ui.control.Label
        FrameEditField                  matlab.ui.control.NumericEditField
        TimeTextAreaLabel               matlab.ui.control.Label
        TimeTextArea                    matlab.ui.control.TextArea
        CLOSEANIMATIONButton            matlab.ui.control.Button
        DraweveryframesEditFieldLabel   matlab.ui.control.Label
        DraweveryframesEditField        matlab.ui.control.NumericEditField
        ShowHideGCSCheckBox             matlab.ui.control.CheckBox
        ShowHideallLCSCheckBox          matlab.ui.control.CheckBox
        ShowHideSegmentsCheckBox        matlab.ui.control.CheckBox
        ShowHideModelMarkersCheckBox    matlab.ui.control.CheckBox
        ShowHideExperimentalMarkersCheckBox  matlab.ui.control.CheckBox
        ShowHideMarkerNamesCheckBox     matlab.ui.control.CheckBox
        ShowHideSegmentLCSListBoxLabel  matlab.ui.control.Label
        ShowHideSegmentLCSListBox       matlab.ui.control.ListBox
        ShowHideSegmentLabel            matlab.ui.control.Label
        ShowHideSegmentsListBox         matlab.ui.control.ListBox
        SetSceneRotationOriginLabel     matlab.ui.control.Label
        SetSceneRotationOriginDropDown  matlab.ui.control.DropDown
        ShowHideJointsCheckBox          matlab.ui.control.CheckBox
        XLimminEditFieldLabel           matlab.ui.control.Label
        XLimminEditField                matlab.ui.control.NumericEditField
        YLimminEditFieldLabel           matlab.ui.control.Label
        YLimminEditField                matlab.ui.control.NumericEditField
        ZLimminEditFieldLabel           matlab.ui.control.Label
        ZLimminEditField                matlab.ui.control.NumericEditField
        XLimmaxEditField_4Label         matlab.ui.control.Label
        XLimmaxEditField                matlab.ui.control.NumericEditField
        YLimmaxEditFieldLabel           matlab.ui.control.Label
        YLimmaxEditField                matlab.ui.control.NumericEditField
        ZLimmaxEditFieldLabel           matlab.ui.control.Label
        ZLimmaxEditField                matlab.ui.control.NumericEditField
    end

    
    methods (Access = private)
        
        %Function to convert the color read from .xml to rgb color which Matlab
        %can read
        function colorrgb = getColor(app,color,SegmentName)
            if strcmp(color,'Black')==1
                colorrgb='k';
            elseif strcmp(color,'Dark Red')==1
                colorrgb=[0.5 0 0];
            elseif strcmp(color,'Red')==1
                colorrgb='r';
            elseif strcmp(color,'Pink')==1
                colorrgb='m';
            elseif strcmp(color,'Rose')==1
                colorrgb=[1 0.6 0.8];
            elseif strcmp(color,'Brown')==1
                colorrgb=[0.6 0.2 0];
            elseif strcmp(color,'Orange')==1
                colorrgb=[1 0.4 0];
            elseif strcmp(color,'Light Orange')==1
                colorrgb=[1 0.6 0];
            elseif strcmp(color,'Gold')==1
                colorrgb=[1 0.8 0];
            elseif strcmp(color,'Tan')==1
                colorrgb=[1 0.8 0.6];
            elseif strcmp(color,'Olive Green')==1
                colorrgb=[0.2 0.2 0];
            elseif strcmp(color,'Dark yellow')==1
                colorrgb=[0.5 0.5 0];
            elseif strcmp(color,'Lime')==1
                colorrgb=[0.6 0.8 0];
            elseif strcmp(color,'Yellow')==1
                colorrgb='y';
            elseif strcmp(color,'Light Yellow')==1
                colorrgb=[1 1 0.6];
            elseif strcmp(color,'Dark Green')==1
                colorrgb=[0 0.2 0];
            elseif strcmp(color,'Green')==1
                colorrgb=[0 0.5 0];
            elseif strcmp(color,'Sea Green')==1
                colorrgb=[0.2 0.6 0.4];
            elseif strcmp(color,'Bright Green')==1
                colorrgb='g';
            elseif strcmp(color,'Light Green')==1
                colorrgb=[0.8 1 0.8];
            elseif strcmp(color,'Dark Teal')==1
                colorrgb=[0 0.2 0.4];
            elseif strcmp(color,'Teal')==1
                colorrgb=[0 0.5 0.5];
            elseif strcmp(color,'Aqua')==1
                colorrgb=[0.2 0.8 0.8];
            elseif strcmp(color,'Turquoise')==1
                colorrgb='c';
            elseif strcmp(color,'Light Turquoise')==1
                colorrgb=[0.8 1 1];
            elseif strcmp(color,'Dark Blue')==1
                colorrgb=[0 0 0.5];
            elseif strcmp(color,'Blue')==1
                colorrgb='b';
            elseif strcmp(color,'Light Blue')==1
                colorrgb=[0.2 0.4 1];
            elseif strcmp(color,'Sky Blue')==1
                colorrgb=[0 0.8 1];
            elseif strcmp(color,'Pale Blue')==1
                colorrgb=[0.6 0.8 1];
            elseif strcmp(color,'Indigo')==1
                colorrgb=[0.2 0.2 0.6];
            elseif strcmp(color,'Blue-Gray')==1
                colorrgb=[0.4 0.4 0.6];
            elseif strcmp(color,'Violet')==1
                colorrgb=[0.5 0 0.5];
            elseif strcmp(color,'Plum')==1
                colorrgb=[0.6 0.2 0.4];
            elseif strcmp(color,'Lavender')==1
                colorrgb=[0.8 0.6 1];
            elseif strcmp(color,'Gray-80%')==1
                colorrgb=[0.2 0.2 0.2];
            elseif strcmp(color,'Gray-50%')==1
                colorrgb=[0.5 0.5 0.5];
            elseif strcmp(color,'Gray-40%')==1
                colorrgb=[0.588 0.588 0.588];
            elseif strcmp(color,'Gray-25%')==1
                colorrgb=[0.753 0.753 0.753];
            elseif strcmp(color,'White')==1
                colorrgb='w';
            else
                msgbox(sprintf([' The color name "',color,'" selected for segment\n', ...
                    '  "',char(SegmentName),'" is not a valid color\n'...
                    ]),'Error');
            end
        end
        
        function [ModelMarkersSphere,MODS,nMarkers]=DrawModelMarkers(app,appax,ModelMarkers,ModelMarkersLocalCoords,nSegments,T_Segments,xoffset,yoffset,zoffset)
            ModelMarkersSphere=cell(nSegments,1);   %Variable where Model Markers graphic objects are saved
            MODS=cell(nSegments,1); %Variable that is the Parent of the Model Markers graphic objects
            
            nMarkers=zeros(nSegments,1);
            
            for i = 1: nSegments
                nMarkers(i,1)=size(ModelMarkersLocalCoords{i,1},1);
                if nMarkers(i,1)>0
                    %First column for the Marker garphic object
                    %Second column for the Marker Name
                    ModelMarkersSphere{i,1}=gobjects(nMarkers(i,1),2);
                    for j=1:nMarkers(i,1)
                        r=ModelMarkers{2,1};    %radius of the marker
                        %Center of the marker sphere
                        x0=ModelMarkersLocalCoords{i,1}{j,1}(1,1);
                        y0=ModelMarkersLocalCoords{i,1}{j,1}(2,1);
                        z0=ModelMarkersLocalCoords{i,1}{j,1}(3,1);
                        
                        %Marker sphere points are calculated
                        [X,Y,Z]=drawsphere(r,x0,y0,z0);
                        
                        %Marker sphere and text are drawn
                        ModelMarkersSphere{i,1}(j,1)=surface(appax,X,Y,Z,'EdgeColor','none','FaceColor', ModelMarkers{3,1});
                        ModelMarkersSphere{i,1}(j,2)=text(appax,(x0+xoffset*r),(y0+yoffset*r),(z0+zoffset*r),ModelMarkersLocalCoords{i,1}{j,2},'interpreter','none','Color',ModelMarkers{3,1});
                        hold(appax, 'on');
                    end
                    %Transform is created and set as parent
                    MODS{i,1}=hgtransform('Parent',appax);
                    set(ModelMarkersSphere{i,1},'Parent',MODS{i,1});
                    
                    %First frame transformation matrix is applied
                    set(MODS{i,1},'Matrix',T_Segments{i,1});
                end
            end
        end
        
        function [MeasuredMarkersSphere,MEDS,nMeasuredMarkers]=DrawMeasuredMarkers(app,appax,MeasuredMarkers,MeasuredMarkersLocalCoords,T_MeasuredMarkers)
            %Number of total Measured Markers
            nMeasuredMarkers=size(MeasuredMarkersLocalCoords,1);
            
            MeasuredMarkersSphere=gobjects(nMeasuredMarkers,1); %Variable where Measured Markers graphic objects are saved
            MEDS=cell(nMeasuredMarkers,1);  %Variable that is the Parent of the Measured Markers graphic objects
            
            for i = 1: nMeasuredMarkers
                r=MeasuredMarkers{2,1}; %radius of the marker
                %Center of the marker sphere
                x0=MeasuredMarkersLocalCoords{i,1}(1,1);
                y0=MeasuredMarkersLocalCoords{i,1}(2,1);
                z0=MeasuredMarkersLocalCoords{i,1}(3,1);
                
                %Marker sphere points are calculated
                [X,Y,Z]=drawsphere(r,x0,y0,z0);
                %Marker sphere is drawn
                MeasuredMarkersSphere(i,1)=surface(appax,X,Y,Z,'EdgeColor','none','FaceColor', MeasuredMarkers{3,1});
                hold(appax, 'on');
                
                %Transform is created and set as parent
                MEDS{i,1}=hgtransform('Parent',appax);
                set(MeasuredMarkersSphere(i,1),'Parent',MEDS{i,1});
                
                %First frame transformation matrix is applied
                set(MEDS{i,1},'Matrix',T_MeasuredMarkers{i,1});
            end
        end
        
        function [AuxWire,ModelMarkerCenter]=DrawAuxWire(app,appax,AuxWireData,MeasuredMarkersLocalCoords,ModelMarkersLocalCoords,nSamples,nSegments,nMeasuredMarkers,T_Segments,T_MeasuredMarkers)
            AuxWire=gobjects(nMeasuredMarkers,1);
            MarkerOrder=cell(nMeasuredMarkers,2);   %Variable to save which model marker must be taken
            %First colum indicates de segment where the model marker is
            %attached
            %Second colum indicates the number of model marker of that
            %particular segment
            for i=1:nMeasuredMarkers
                for j=1:nSegments
                    if ~isempty(ModelMarkersLocalCoords{j,1})
                        for k=1:size(ModelMarkersLocalCoords{j,1},1)
                            if strcmp(ModelMarkersLocalCoords{j,1}{k,2},MeasuredMarkersLocalCoords{i,2})==1
                                MarkerOrder{i,1}=j; %Save the segment where the rigid marker is attached
                                MarkerOrder{i,2}=k; %Save the number of that marker in the j segment
                                break;
                            end
                        end
                    end
                end
            end
            %Aux Wires for the first frame are drawn
            ModelMarkerCenter=cell(nMeasuredMarkers,nSamples);
            for i=1:nMeasuredMarkers
                segment=MarkerOrder{i,1};
                numberofmarker=MarkerOrder{i,2};
                for j=1:nSamples
                    ModelMarkerCenter{i,j}=ones(4,1);
                    ModelMarkerCenter{i,j}(1:3,1)=ModelMarkersLocalCoords{segment,1}{numberofmarker,1};
                    ModelMarkerCenter{i,j}=T_Segments{segment,j}*ModelMarkerCenter{i,j};
                end
                x=[ModelMarkerCenter{i,1}(1,1) T_MeasuredMarkers{i,1}(1,4)];
                y=[ModelMarkerCenter{i,1}(2,1) T_MeasuredMarkers{i,1}(2,4)];
                z=[ModelMarkerCenter{i,1}(3,1) T_MeasuredMarkers{i,1}(3,4)];
                hold(appax, 'on');
                AuxWire(i,1)=line(appax,x,y,z,'LineWidth',AuxWireData{1,1}*1000,'Color',AuxWireData{1,3});
                %If experimental Marker is at the origin it means it
                %has not been read correctly so no line is drawn
                if T_MeasuredMarkers{i,1}(1,4)==0 && T_MeasuredMarkers{i,1}(2,4)==0 && T_MeasuredMarkers{i,1}(3,4)==0
                    set(AuxWire(i,1),'Visible','off');
                else
                end
            end
        end
        
        function [JointsSphere,JNT,nJoints]=DrawJoints(app,appax,Joints,nSegments,T_Segments)
            JointsSphere=cell(nSegments,1);   %Variable where Joints graphic objects are saved
            JNT=cell(nSegments,1); %Variable that is the Parent of the Joints graphic objects
            
            nJoints=zeros(nSegments,1);
            
            for i = 1: nSegments
                if ~isempty(Joints{1,1}{i,1})
                    %First column for the Marker garphic object
                    %Second column for the Marker Name
                    nJoints(i,1)=size(Joints{1,1}{i,1},1);
                    JointsSphere{i,1}=gobjects(nJoints(i,1),1);
                    for j=1:nJoints(i,1)
                        r=Joints{2,1};    %radius of the joint
                        %Center of the marker sphere
                        x0=Joints{1,1}{i,1}{j,1}(1,1);
                        y0=Joints{1,1}{i,1}{j,1}(2,1);
                        z0=Joints{1,1}{i,1}{j,1}(3,1);
                        
                        %Joint sphere points are calculated
                        [X,Y,Z]=drawsphere(r,x0,y0,z0);
                        
                        %Joint sphere and text are drawn
                        JointsSphere{i,1}(j,1)=surface(appax,X,Y,Z,'EdgeColor','none','FaceColor', Joints{3,1});
                        %                         JointsSphere{i,1}(j,2)=text(appax,x0,(y0+1.5*r),z0,JointName,'Color',Joints{3,1});
                        hold(appax, 'on');
                    end
                    %Transform is created and set as parent
                    JNT{i,1}=hgtransform('Parent',appax);
                    set(JointsSphere{i,1},'Parent',JNT{i,1});
                    
                    %First frame transformation matrix is applied
                    set(JNT{i,1},'Matrix',T_Segments{i,1});
                end
            end
        end
        
        function T_Segments = checkAndCorrectRotMatrices(app, T_Segments, SegmentNames)
            
            [nSegments,nSamples] = size(T_Segments);
            
            FrameIndexVec = cell(nSegments,1);
            normValue     = zeros(nSamples,nSegments);
            dotProduct    = zeros(nSamples,nSegments);
            nBadFrames    = zeros(nSegments,1);
            
            for i=1:nSegments           
                
                
                for j=1:nSamples
                    
                    R = T_Segments{i,j}(1:3,1:3);
                    
                    normValue(j,i)  = norm(R' * R);
                    dotProduct(j,i) = abs(dot(R(1:3,1), R(1:3,2))) + abs(dot(R(1:3,2), R(1:3,3))) + abs(dot(R(1:3,3), R(1:3,1)));
                    
                    if normValue(j,i) < 0.995 || normValue(j,i) > 1.005
                        
                        % Rotation matrix is NOT orthogonal.
                        
                        % store frame index of no orthogonal matrix
                        FrameIndexVec{i} = [FrameIndexVec{i}; j];
                        
                        
                        % METHOD 1: Make R orthogonal manually
                        x = R(:,1)./norm(R(:,1));
                        z = cross(x,R(:,2));
                        z = z./norm(z);
                        y = cross(z,x);
                        y = y./norm(y);                        
                        R = [x, y, z];
                        
                        % METHOD 2: 
                        %R2 = R * sqrt(R' * R);
                        
                        % store orthogonalized matrix
                        T_Segments{i,j}(1:3,1:3) = R;
                        
                    else
                        % Rotation matrix is orthogonal                        
                    end                    
                end
                nBadFrames(i) = length(FrameIndexVec{i});

%                 if max(nBadFrames(i)) > 2
%                     warndlg(sprintf(['Segment ',SegmentNames{i},' may have an error on its definition. \n',...
%                                      'The rotation matrix is not orthogonal for ',num2str(nBadFrames(i)),' frames.\n',...
%                                      'Possible causes:\n',...
%                                      ' 1) Bad model initialization due to missing markers in the 1st frames\n' ...
%                                      ' 2) Error in the definition of points, vectors or joints in your model.\n' ...
%                                      '  \n' ...
%                                      'Rotation matrices have been fixed in order to visualize the results.\n' ...
%                                      'BUT YOU SHOULD CHECK YOUR MODEL.\n' ...
%                                      ]),'Warning');
%                 end
            end
            
        end    
    end   


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Variable that enables closing the app by pressing the CLOSE
            %APP button before an animation has been loaded
            global closenointeraction
            closenointeraction=1;
            %Variable to control slider interaction
            global slide
            slide=0;
            %Set UIFigure in the correct position depending on OS
            UIFigureSize=get(app.MotViewUIFigure,'Position');
            screensize=get(groot,'ScreenSize');
            if ispc
                windowstitlebar=28;
                windowstaskbar=40;
                %set(app.MotViewUIFigure,'Position',[1 screensize(1,4)-UIFigureSize(1,4)-screensize(1,4)*(7/270) UIFigureSize(1,3) UIFigureSize(1,4)])
                if screensize(1,4)-UIFigureSize(1,4)-windowstitlebar<windowstaskbar
                    value2=windowstaskbar;
                else
                    value2=screensize(1,4)-UIFigureSize(1,4)-windowstitlebar;
                end
                if UIFigureSize(1,4)>screensize(1,4)-windowstitlebar-windowstaskbar
                    value4=screensize(1,4)-windowstitlebar-windowstaskbar;
                else
                    value4=UIFigureSize(1,4);
                end
                set(app.MotViewUIFigure,'Position',[1 value2 UIFigureSize(1,3) value4]);
            elseif ismac
                mactitlebar=28;
                mactaskbar=40;
                if screensize(1,4)-UIFigureSize(1,4)-mactitlebar<mactaskbar
                    value2=mactaskbar;
                else
                    value2=screensize(1,4)-UIFigureSize(1,4)-mactitlebar;
                end
                if UIFigureSize(1,4)>screensize(1,4)-mactitlebar-mactaskbar
                    value4=screensize(1,4)-mactitlebar-mactaskbar;
                else
                    value4=UIFigureSize(1,4);
                end
                set(app.MotViewUIFigure,'Position',[1 value2 UIFigureSize(1,3) value4]);
            end
        end

        % Button pushed function: LoadAnimationButton
        function LoadAnimationButtonPushed(app, event)
            try
                %Variable that enables closing the app by pressing the CLOSE
                %APP button before an animation has been loaded
                global closenointeraction
                closenointeraction=0;
                
                %Variable to control slider interaction
                global slide
                
                %Variable to control experimental markers visibility
                global markershided
                markershided=0;
                
                %Close the 3D Window
                global appfigure
                if (exist('appfigure','var'))~=0
                    if ishandle(appfigure)==1
                        close(appfigure);
                    end
                    clear appfigure;
                end
                
                %Disable Warnings
                warning('off','all');
                
                %Reset everything on the app
                app.FrameSlider.Value = 0;
                app.PauseButton.Value = false;
                %             cla(appax,'reset');
                
                % ===========================================================================
                % Declare user variables ====================================================
                extra1=1.5;  %Extra factor to multiply to the upper axis limit
                extra2=0.5; %Extra factor to multiply to the lower axis limit
                GCSlength=1;    %Length of the GCS
                LCSlength=0.6;  %Length of the LCS
                xoffset=0;  %Offset in x coordinate of the marker names
                yoffset=2;    %Offset in y coordinate of the marker names
                zoffset=0;  %Offset in z coordinate of the marker names
                WireFacets=20;  %Number of Facets of the Wire Cylinders Draw Sequence
                % Declare user variables ====================================================
                % ===========================================================================
                
                %Declare global variables to carry on the animation
                global PlayForward
                global PlayBackward
                global FrameForward
                global FrameBackward
                global PauseAnimation
                global StopAnimation
                PlayForward = 0;
                PlayBackward = 0;
                FrameForward = 0;
                FrameBackward = 0;
                PauseAnimation = 1;
                StopAnimation = 0;
                
                global frame
                frame = 0;
                
                maxLCS=0; %Variable to save coordinate of longest LCS axis of all of them
                
                %Get path and name of the .mat file
                [name_matfile,partialpath_matfile] = uigetfile;
                
                fullpath_matfile = fullfile(partialpath_matfile,name_matfile);
                
                %Create figure and axes in matlab
                screensize = get(groot,'Screensize');
                UIFigureSize=get(app.MotViewUIFigure,'Position');
                global appfigure
                global appax
                if ispc
                    windowstaskbar=40;
                    appfigure=figure('OuterPosition',[UIFigureSize(1,3) windowstaskbar screensize(1,3)-UIFigureSize(1,3) screensize(1,4)-windowstaskbar]);
                elseif ismac
                    mactaskbar=40;
                    appfigure=figure('OuterPosition',[UIFigureSize(1,3) mactaskbar screensize(1,3)-UIFigureSize(1,3) screensize(1,4)-mactaskbar]);
                end
                appax=axes(appfigure);
                
                %Display filename as axes title
                annotation(appfigure,'textbox',[0 .7 .3 .3],'String',name_matfile,'interpreter','none','FitBoxToText','on','EdgeColor','none','FontSize',14,'FontWeight','bold');
                
                %Bring app and figure to front
                set(app.MotViewUIFigure,'Visible','off');
                set(app.MotViewUIFigure,'Visible','on');
                set(appfigure,'Visible','off');
                set(appfigure,'Visible','on');
                
                %Load the .MAT file
                global T_Segments
                global T_ModelMarkers
                global T_MeasuredMarkers
                global Time
                
                Matfile = load(fullpath_matfile);
                T_Segments = Matfile.T_Segments;
                T_ModelMarkers = Matfile.T_ModelMarkers;
                T_MeasuredMarkers = Matfile.T_MeasuredMarkers;
                Time = Matfile.Time;
                R = Matfile.R;
                GraphicsPath = Matfile.GraphicsPath;
                RelPath = Matfile.RelPath;
                ModelMarkers=Matfile.ModelMarkers;
                ModelMarkersLocalCoords=ModelMarkers{1,1};
                MeasuredMarkers=Matfile.MeasuredMarkers;
                MeasuredMarkersLocalCoords=MeasuredMarkers{1,1};
                WireframeLocalCoords=Matfile.WireframeLocalCoords;
                SegmentNames=Matfile.Segments;
                AuxWireData=Matfile.AuxWire;
                Joints=Matfile.Joints;
                maxd=Matfile.maxd;

                % check rotation matrices for possible non-orthogonality
                % issues and make it orthogonal to view a 3D representation
                T_Segments = checkAndCorrectRotMatrices(app, T_Segments, SegmentNames);

                
                %Print Segment Names on the List Boxes
                app.ShowHideSegmentLCSListBox.Items=SegmentNames;
                app.ShowHideSegmentsListBox.Items=SegmentNames;
                app.SetSceneRotationOriginDropDown.Items=SegmentNames;
                
                global nSegments
                [nSegments,nSamples] = size(T_Segments);
                
                %Set limits of Slider and Frame Field
                app.FrameSlider.Limits = [0,nSamples-1];
                app.FrameEditField.Limits = [0,nSamples-1];
                app.DraweveryframesEditField.Limits = [1,nSamples-1];
                
                %Draw Model Markers in Global Coordinates
                global ModelMarkersSphere
                global MODS
                global nMarkers %Array that indicates number of model markers attached to each segment
                [ModelMarkersSphere,MODS,nMarkers]=DrawModelMarkers(app,appax,ModelMarkers,ModelMarkersLocalCoords,nSegments,T_Segments,xoffset,yoffset,zoffset);
                hold(appax, 'on');
                
                %Draw Measured Markers in Global Coordinates
                global MeasuredMarkersSphere
                global MEDS
                [MeasuredMarkersSphere,MEDS,nMeasuredMarkers]=DrawMeasuredMarkers(app,appax,MeasuredMarkers,MeasuredMarkersLocalCoords,T_MeasuredMarkers);
                hold(appax, 'on');
                
                %Draw Auxiliar Wire between the center of each model and
                %experimental marker
                global ModelMarkerCenter    %Varibale where Auxiliar Wire beginning and ending points for each frame are saved
                global AuxWire  %Variable where Auxiliar Wire graphic objects are saved
                [AuxWire,ModelMarkerCenter]=DrawAuxWire(app,appax,AuxWireData,MeasuredMarkersLocalCoords,ModelMarkersLocalCoords,nSamples,nSegments,nMeasuredMarkers,T_Segments,T_MeasuredMarkers);
                
                
                %Initialize cell variables for segment graphic objects
                global h    %Stores segment and LCS graphic objects
                global H    %Tranform that is set as Parent
                global nGraphFiles  %Initialize array variable that contains the number of graphic objects for each segment
                
                h = cell(nSegments,2);
                H = cell(nSegments,3);
                LCSystems=cell(nSegments,1);
                nGraphFiles = zeros(nSegments,1);
                
                % Draw the Graphic Object for each segment
                for i=1:nSegments
                    
                    %Write Segment name
                    H{i,3}=SegmentNames(i,1);
                    
                    %Check if the Graphic Object is a cylinder
                    if ~isempty(R.Subject.Segments(i).WireFrame)
                        DrawSeq = R.Subject.Segments(i).WireFrame.DrawSeq;
                        if ~isempty(R.Subject.Segments(i).WireFrame.Color)
                            WireColor = R.Subject.Segments(i).WireFrame.Color;
                            WireColorrgb=getColor(app,WireColor,SegmentNames(i,1));
                        end
                        if ~isempty(R.Subject.Segments(i).WireFrame.Radius)
                            WireRadius = str2num(R.Subject.Segments(i).WireFrame.Radius);
                        end
                        
                        nDrawSeq=size(DrawSeq,1);
                        nGraphFiles(i,1)=nDrawSeq-1;
                        h{i,1}=gobjects(nGraphFiles(i,1),1);
                        h{i,2}=hggroup(appax);
                        
                        %Draw Cylinders
                        for j=1:nGraphFiles(i,1)
                            r1=WireframeLocalCoords{i,1}{j,1};
                            r2=WireframeLocalCoords{i,1}{j+1,1};
                            
                            [X, Y, Z]=cylinder2P(WireRadius,WireFacets,r1,r2);
                            
                            h{i,1}(j,1)=surface(appax,X,Y,Z,'FaceColor',WireColorrgb,'EdgeColor','none');
                            hold(appax, 'on');
                            %Draw LCS
                            if j==1 && strcmp(H{i,3},'Ground')~=1
                                %LCSystems{i,1} = LCSplot(appax,X,Y,Z,H{i,3});
                                LCSystems{i,1} = LCSplot(appax,LCSlength,0,0,H{i,3});
                                set(LCSystems{i,1},'Parent',h{i,2});
                                %Get maximum length of all LCS
                                maxtmp=max(abs((LCSystems{i,1}(1,2).Position)));
                                if maxtmp>maxLCS
                                    maxLCS=maxtmp;
                                    longestsegment=i;
                                end
                            end
                        end
                        % Create an hgtransform group and set it as a parent of the patch
                        H{i,1}=hgtransform(appax);
                        set(h{i,1},'Parent',H{i,1});
                        H{i,2}=hgtransform(appax);
                        set(h{i,2},'Parent',H{i,2});
                        
                        %Convert to global coordinates
                        set(H{i,1},'Matrix',T_Segments{i,1});
                        set(H{i,2},'Matrix',T_Segments{i,1});
                        hold(appax, 'on');
                    end
                    
                    %Check if the Graphic Object is an .stl file
                    GraphFile = R.Subject.Segments(i).Graphic;
                    if iscell(GraphFile)  &&  ~isempty(GraphFile)
                        nGraphFiles(i,1) = size(GraphFile,1);
                        h{i,1} = gobjects(nGraphFiles(i,1),1);
                        h{i,2}=hggroup(appax);
                        
                        for j=1:nGraphFiles(i,1)
                            %Read the stl file
                            stlfilename = GraphFile{j,1};
                            %fullpath_stlfile = fullfile(GraphicsPath,stlfilename);
                            fullpath_stlfile = fullfile([pwd,RelPath(11:end)],stlfilename);
                            object = stlread(fullpath_stlfile);
                            
                            %Translate, rotate and scale the patch
                            Trans=GraphFile{1,2}';
                            XRot=makehgtform('xrotate',GraphFile{1,3}(1,1));
                            XRot=XRot(1:3,1:3);
                            YRot=makehgtform('yrotate',GraphFile{1,3}(2,1));
                            YRot=YRot(1:3,1:3);
                            ZRot=makehgtform('zrotate',GraphFile{1,3}(3,1));
                            ZRot=ZRot(1:3,1:3);
                            Rot=XRot*YRot*ZRot;
                            Scal=makehgtform('scale',GraphFile{1,4});
                            Scal=Scal(1:3,1:3);
                            
                            for k=1:size(object.vertices,1)
                                object.vertices(k,:)=object.vertices(k,:)+Trans;
                                object.vertices(k,:)=object.vertices(k,:)*Rot;
                                %                                 object.vertices(k,:)=object.vertices(k,:)*XRot;
                                %                                 object.vertices(k,:)=object.vertices(k,:)*YRot;
                                %                                 object.vertices(k,:)=object.vertices(k,:)*ZRot;
                                object.vertices(k,:)=object.vertices(k,:)*Scal;
                            end
                            
                            %Draw the stl file
                            h{i,1}(j,1) = patch(appax,object,'FaceColor',       [0.8 0.8 1.0], ...
                                'EdgeColor',       'none',        ...
                                'AmbientStrength', 0.7);
                            hold(appax, 'on');
                            %Draw LCS
                            if j==1 && strcmp(H{i,3},'Ground')~=1
                                X=h{i,1}(j,1).Vertices(:,1);
                                Y=h{i,1}(j,1).Vertices(:,2);
                                Z=h{i,1}(j,1).Vertices(:,3);
                                %LCSystems{i,1} = LCSplot(appax,X,Y,Z,H{i,3});
                                LCSystems{i,1} = LCSplot(appax,LCSlength,0,0,H{i,3});
                                set(LCSystems{i,1},'Parent',h{i,2});
                                %Get maximum length of all LCS
                                maxtmp=max(abs((LCSystems{i,1}(1,2).Position)));
                                if maxtmp>maxLCS
                                    maxLCS=maxtmp;
                                    longestsegment=i;
                                end
                            end
                        end
                        % Create an hgtransform group and set it as a parent of the patch
                        H{i,1}=hgtransform(appax);
                        set(h{i,1},'Parent',H{i,1});
                        H{i,2}=hgtransform(appax);
                        set(h{i,2},'Parent',H{i,2});
                        
                        % Convert to global coordinates
                        set(H{i,1},'Matrix',T_Segments{i,1});
                        set(H{i,2},'Matrix',T_Segments{i,1});
                    end
                end
                %Convert maxLCS to GLobal Coordinates
                maxLCS=maxLCS+maxd(longestsegment,1);
                
                %Draw Joints in Global Coordinates
                global JNT
                global JointsSphere
                global nJoints %Array that indicates number of model markers attached to each segment
                [JointsSphere,JNT,nJoints]=DrawJoints(app,appax,Joints,nSegments,T_Segments);
                
                
                %Draw GCS
                global GCS
                GCS=hggroup(appax);
                for i=1:nSegments
                    if strcmp(H{i,3},'Ground')==1
                        GCSystem=LCSplot(appax,GCSlength,0,0,H{i,3});
                    end
                end
                set(GCSystem,'Parent',GCS);
                
                %Adjust camera, light, axis
                c=camlight(appax,'headlight');
                material(appax,'dull');
                axis(appax,'on');
                axis(appax,'equal');
                %Set axis limit to fit all the objects drawn
                axis(appax,'image');
                axislimit=zeros(3,2);
                axislimit(1,1)=appax.XLim(1,1);
                axislimit(1,2)=appax.XLim(1,2);
                axislimit(2,1)=appax.YLim(1,1);
                axislimit(2,2)=appax.YLim(1,2);
                axislimit(3,1)=appax.ZLim(1,1);
                axislimit(3,2)=appax.ZLim(1,2);
                for i=1:3
                    if axislimit(i,1)<0
                        axislimit(i,1)=extra1*axislimit(i,1);
                    else
                        axislimit(i,1)=extra2*axislimit(i,1);
                    end
                    if axislimit(i,2)>0
                        axislimit(i,2)=extra1*axislimit(i,2);
                    else
                        axislimit(i,2)=extra2*axislimit(i,2);
                    end
                end
                %Get Measured Markers Limits
                markerslimit=zeros(3,2);
                for i=1:3
                    if Matfile.maxCoord(i,1)<0
                        markerslimit(i,1)=extra1*Matfile.maxCoord(i,1);
                    else
                        markerslimit(i,1)=extra2*Matfile.maxCoord(i,1);
                    end
                    if Matfile.maxCoord(i,2)>0
                        markerslimit(i,2)=extra1*Matfile.maxCoord(i,2);
                    else
                        markerslimit(i,2)=extra2*Matfile.maxCoord(i,2);
                    end
                end
                %Compare Measured Markers limits to actual axis limits
                for i=1:3
                    if markerslimit(i,1)<axislimit(i,1)
                        axislimit(i,1)=markerslimit(i,1);
                    end
                    if markerslimit(i,2)>axislimit(i,2)
                        axislimit(i,2)=markerslimit(i,2);
                    end
                end
                %Compare Longest LCS limit to actual axis limits
                for i=1:3
                    if -maxLCS<axislimit(i,1)
                        axislimit(i,1)=-maxLCS;
                    end
                    if maxLCS>axislimit(i,2)
                        axislimit(i,2)=maxLCS;
                    end
                end
                
                %Round axis limits to 2 decimal places
                for i=1:3
                    axislimit(i,1)=round(axislimit(i,1),2);
                    axislimit(i,2)=round(axislimit(i,2),2);
                end
                %Print axis limits on boxes
                app.XLimminEditField.Value=axislimit(1,1);
                app.XLimmaxEditField.Value=axislimit(1,2);
                app.YLimminEditField.Value=axislimit(2,1);
                app.YLimmaxEditField.Value=axislimit(2,2);
                app.ZLimminEditField.Value=axislimit(3,1);
                app.ZLimmaxEditField.Value=axislimit(3,2);
                %Set axis limits
                axis(appax,([axislimit(1,1) axislimit(1,2),axislimit(2,1) axislimit(2,2),axislimit(3,1) axislimit(3,2)]));
                axis(appax,'manual');
                grid(appax,'off');
                %Delete axis ticks
                set(appax,'XTickMode','manual');
                set(appax,'YTickMode','manual');
                set(appax,'ZTickMode','manual');
                set(appax,'XTick',[]);
                set(appax,'YTick',[]);
                set(appax,'ZTick',[]);
                %Set the axis as tight as possible in the figure
                InSet = get(appax, 'TightInset');
                set(appax, 'Position', [InSet(1:2), 1-InSet(1)-InSet(3), 1-InSet(2)-InSet(4)]);
                set(appax,'CameraUpVector',[0 1 0],'CameraUpVectorMode','manual');
                %Display Cameratoolbar
                cameratoolbar(appfigure,'Show');
                axis(appax,'off');
                drawnow;
                %End of loading code
                
                i = 0;
                app.FrameEditField.Value=0;
                app.FrameSlider.Value = 0;
                activation = 0;   %Initialize variable that controls whether it enters the first Pausing Code or not
                app.Lamp.Color='g'; %Turns lamp green which indicates that the animation is ready to be played
                
                %Pausing code after loading the animation until user presses a
                %button to start
                while(1)
                    pause(0.01);
                    if PlayForward==1 && frame<nSamples-1
                        slide=0;
                        break;
                    elseif PlayBackward==1 && frame>0
                        slide=0;
                        break;
                    elseif FrameForward==1 && frame<nSamples-1
                        i=1;
                        frame=1;
                        activation=1;
                        slide=0;
                        break;
                    elseif FrameBackward==1 && frame>0
                        activation=1;
                        slide=0;
                        break;
                    elseif app.FrameEditField.Value~=0
                        frame=app.FrameEditField.Value;
                        i=frame;
                        activation=1;
                        slide=0;
                        break;
                        %Closing Code
                    elseif StopAnimation==1
                        slide=0;
                        break;
                        %End of Closing Code
                    else
                        PauseButtonValueChanged(app, event)
                    end
                end
                
                %Animation Loop
                while i>=0 && i<=(nSamples-1)
                    
                    %Closing Code
                    if StopAnimation==1
                        break;
                    end
                    %End of Closing Code
                    
                    %Pausing code
                    if PauseAnimation == 1 && activation == 0
                        while(1)
                            pause(0.01);
                            
                            %Closing Code
                            if StopAnimation==1
                                slide=0;
                                break;
                            end
                            %End of Closing Code
                            
                            if PauseAnimation==0
                                slide=0;
                                break;
                            end
                            
                            if FrameForward==1
                                i=i+1;
                                frame=frame+1;
                                activation=1;
                                slide=0;
                                break;
                            elseif FrameBackward==1
                                i=i-1;
                                frame=frame-1;
                                activation=1;
                                slide=0;
                                break;
                            end
                            
                            if app.FrameEditField.Value~=frame+1
                                frame=app.FrameEditField.Value;
                                i=frame;
                                slide=0;
                                break;
                            end
                            
                        end
                    end
                    %End of pausing code
                    
                    if i~=frame
                        i=frame;
                    end
                    
                    %Measured Markers and Aux Wires Drawing Code
                    for j=1:nMeasuredMarkers
                        
                        if slide==1
                            %slide=0;
                            break;
                        end
                        
                        %Closing Code
                        if StopAnimation==1
                            slide=0;
                            break;
                        end
                        %End of Closing Code
                        
                        %If experimental Marker is at the origin it means it
                        %has not been read correctly so the marker is not drawn
                        if T_MeasuredMarkers{j,i+1}(1,4)==0 && T_MeasuredMarkers{j,i+1}(2,4)==0 && T_MeasuredMarkers{j,i+1}(3,4)==0
                            set(MEDS{j,1},'Visible','off');
                        else
                            T = T_MeasuredMarkers{j,(i+1)};
                            set(MEDS{j,1},'Matrix',T);
                            if strcmp(MEDS{j,1}.Visible,'off')==1 && markershided~=1
                                set(MEDS{j,1},'Visible','on');
                            end
                        end
                        
                        %If experimental Marker is at the origin it means it
                        %has not been read correctly so no line is drawn
                        if T_MeasuredMarkers{j,i+1}(1,4)==0 && T_MeasuredMarkers{j,i+1}(2,4)==0 && T_MeasuredMarkers{j,i+1}(3,4)==0
                            set(AuxWire(j,1),'Visible','off');
                        else
                            x=[ModelMarkerCenter{j,i+1}(1,1) T_MeasuredMarkers{j,i+1}(1,4)];
                            y=[ModelMarkerCenter{j,i+1}(2,1) T_MeasuredMarkers{j,i+1}(2,4)];
                            z=[ModelMarkerCenter{j,i+1}(3,1) T_MeasuredMarkers{j,i+1}(3,4)];
                            hold(appax, 'on');
                            set(AuxWire(j,1),'XData',x,'YData',y,'ZData',z);
                            if strcmp(AuxWire(j,1).Visible,'off')==1
                                set(AuxWire(j,1),'Visible','on');
                            end
                        end
                    end
                    
                    for j = 1:nSegments
                        
                        if slide==1
                            %slide=0;
                            break;
                        end
                        
                        %Closing Code
                        if StopAnimation==1
                            slide=0;
                            break;
                        end
                        %End of Closing Code
                        
                        %Model Markers Drawing Code
                        if nMarkers(j,1)>0
                            T = T_Segments{j,(i+1)};
                            set(MODS{j,1},'Matrix',T);
                        end
                        
                        %Joints Drawing Code
                        if nJoints(j,1)>0
                            T = T_Segments{j,(i+1)};
                            set(JNT{j,1},'Matrix',T);
                        end
                        
                        %Graphic Objects Drawing Code
                        if nGraphFiles(j,1)>0
                            T = T_Segments{j,(i+1)};    %Obtain the transformation matrix T from the .MAT file
                            set(H{j,1},'Matrix',T);    % Update the object to new position using Transformation Matrix
                            set(H{j,2},'Matrix',T);    % Update the object's LCS to new position using Transformation Matrix
                            app.TimeTextArea.Value = num2str(Time{1,(i+1)});   %Update Time box
                            app.FrameEditField.Value=frame; %Print frame number in frame box
                            app.FrameSlider.Value=frame;    %Print frame number in frame slider
                        end
                    end
                    %End of Drawing Code
                    
                    drawnow;
                    
                    if slide==1
                        slide=0;
                        continue;
                    end
                    
                    %Second Pausing Code
                    if PauseAnimation==1
                        while (1)
                            
                            pause(0.01);
                            
                            %Closing Code
                            if StopAnimation==1
                                slide=0;
                                break;
                            end
                            %End of Closing Code
                            
                            %Quits loop if it has reached first or last frame
                            %using the slider
                            if slide==1 && frame==nSamples-1
                                break;
                            elseif slide==1 && frame==0
                                break;
                            end
                            
                            if activation == 1
                                FrameForward=0;
                                FrameBackward=0;
                                activation=0;
                            elseif activation==2
                                activation=0;
                            end
                            
                            if PauseAnimation==0
                                slide=0;
                                break;
                            end
                            
                            if FrameForward==1 || FrameBackward==1
                                activation=1;
                                slide=0;
                                break;
                            end
                            
                            if app.FrameEditField.Value~=frame
                                frame=app.FrameEditField.Value;
                                i=frame;
                                activation=2;
                                slide=0;
                                break;
                            end
                        end
                    end
                    %End of Second Pausing Code
                    
                    %Frame increase/decrease depending on the situation
                    if activation~=2 && activation~=3 && slide==0
                        if PlayForward==1
                            i=i+app.DraweveryframesEditField.Value;
                            frame=frame+app.DraweveryframesEditField.Value;
                        elseif PlayBackward==1
                            i=i-app.DraweveryframesEditField.Value;
                            frame=frame-app.DraweveryframesEditField.Value;
                        elseif FrameForward==1
                            i=i+1;
                            frame=frame+1;
                        elseif FrameBackward==1
                            i=i-1;
                            frame=frame-1;
                        end
                    elseif activation==3 && slide==0
                        activation=0;
                        if i==0
                            i=i-1;
                            frame=frame-1;
                        elseif i==nSamples-1
                            i=i+1;
                            frame=frame+1;
                        end
                    elseif slide==1 && frame==nSamples-1
                        frame=frame+1;
                        i=frame;
                        slide=0;
                    elseif slide==1 && frame==0
                        frame=frame-1;
                        i=frame;
                        slide=0;
                    end
                    
                    %Pausing Code after the Animation reaches first or last
                    %frame
                    %The Animation can be restarted from here by pressing a
                    %button, moving the slider or changing the frame box
                    if i==-1 || i==nSamples
                        app.PauseButton.Value=true;
                        PauseAnimation=1;
                        PlayForward=0;
                        PlayBackward=0;
                        FrameForward=0;
                        FrameBackward=0;
                        comparevalue=app.FrameEditField.Value;
                        while(1)
                            %Closing Code
                            if StopAnimation==1
                                slide=0;
                                break;
                            end
                            %End of Closing Code
                            if PlayForward==1 && i==-1
                                i=0;
                                frame=0;
                                slide=0;
                                break;
                            elseif PlayBackward==1 && i==nSamples
                                i=nSamples-1;
                                frame=nSamples-1;
                                slide=0;
                                break;
                            elseif FrameForward==1 && i==-1
                                i=1;
                                frame=1;
                                activation=1;
                                slide=0;
                                break;
                            elseif FrameBackward==1 && i==nSamples
                                i=nSamples-2;
                                frame=nSamples-2;
                                activation=1;
                                slide=0;
                                break;
                            elseif app.FrameEditField.Value~=comparevalue
                                frame=app.FrameEditField.Value;
                                i=frame;
                                activation=2;
                                slide=0;
                                break;
                            else
                                PauseButtonValueChanged(app, event)
                            end
                            pause(0.01);
                        end
                    elseif i<=-1
                        i=0;
                        frame=0;
                        activation=3;
                    elseif i>=nSamples
                        i=nSamples-1;
                        frame=nSamples-1;
                        activation=3;
                    end
                    
                end
                
                %Enable Warnings
                warning('on','all');
                
                %Close App and figure with axes
                if isvalid(appfigure)==1
                    close(appfigure);
                end
                close(app.MotViewUIFigure);
            catch ME
                if isvalid(app)==0
                    if (exist('appfigure','var'))~=0
                        if isvalid(appfigure)==1
                            close(appfigure);
                        end
                    end
                    return;
                else
                    if (exist('appfigure','var'))~=0
                        if isvalid(appfigure)==1
                            close(appfigure);
                        end
                    end
                    if isa(name_matfile,'double')==1
                        if name_matfile==0 || partialpath_matfile==0
                            msgbox('No file selected. Please select one.','Error');
                        end
                    elseif isa(name_matfile,'char')==1
                        [FileName , Extension] = getFilenameAndExt(name_matfile);
                        if strcmpi(Extension,'mat')==0
                            msgbox('File extension is not correct. Please select another file.','Error');
                        
                        elseif strcmp(ME.identifier,'MATLAB:hg:BadTransformMatrix')
                            msgbox(sprintf(['The rotation matrix of at least one segment is not orthogonal\n' ...
                                'Possible causes:\n',...
                                '  1) Bad model initialization due to missing markers in the 1st frames\n' ...
                                '  2) Error in the definition of points, vectors or joints in your model.\n' ...
                                '  \n' ...
                                'Please fix your model and run the motion reconstruction again']) ...
                                ,'Error');
                        else
                            msgbox(sprintf([' The following unexpected error ocurred:\n', ...
                                            '   ', ME.identifier,'\n'...
                                            '   ', ME.message,'\n'...
                                            '   Error origin: function ', ME.stack(1).name,'\n'...
                                            ' Please fix your model and run the motion reconstruction again']),'Error');
                        end
                    else
                        msgbox(sprintf([' The following unexpected error ocurred:\n', ...
                            '   ', ME.identifier,'\n'...
                            '   ', ME.message,'\n'...
                            '   Error origin: function ', ME.stack(1).name,'\n'...
                            ' Please fix your model and run the motion reconstruction again']),'Error');                        
                    end
                    app.Lamp.Color='r'; %Turns lamp red which indicates that the animation is NOT ready to be played
                    clearvars -except closenointeraction slide appfigure
                    closenointeraction=1;
                    slide=0;
                    return;
                end
            end
        end

        % Button pushed function: CLOSEANIMATIONButton
        function CLOSEANIMATIONButtonPushed(app, event)
            global StopAnimation
            global closenointeraction
            StopAnimation = 1;
            if closenointeraction==1
                close(app.MotViewUIFigure);
            end
        end

        % Value changing function: FrameSlider
        function FrameSliderValueChanging(app, event)
            try
                rounded=round(event.Value,0);
                
                global H
                global MODS MEDS JNT
                global AuxWire
                global ModelMarkerCenter
                global T_Segments T_MeasuredMarkers
                global Time
                global frame
                
                global slide
                slide=1;
                
                nSegments = size(T_Segments,1);
                nMeasuredMarkers = size(T_MeasuredMarkers,1);
                
                i = rounded;
                frame = i;
                for j = 1:nMeasuredMarkers
                    %If experimental Marker is at the origin it means it
                    %has not been read correctly so no line is drawn
                    if T_MeasuredMarkers{j,i+1}(1,4)==0 && T_MeasuredMarkers{j,i+1}(2,4)==0 && T_MeasuredMarkers{j,i+1}(3,4)==0
                        set(MEDS{j,1},'Visible','off');
                    else
                        T = T_MeasuredMarkers{j,(i+1)};
                        set(MEDS{j,1},'Matrix',T);
                        if strcmp(MEDS{j,1}.Visible,'off')==1
                            set(MEDS{j,1},'Visible','on');
                        end
                    end
                    
                    %If experimental Marker is at the origin it means it
                    %has not been read correctly so no line is drawn
                    if T_MeasuredMarkers{j,i+1}(1,4)==0 && T_MeasuredMarkers{j,i+1}(2,4)==0 && T_MeasuredMarkers{j,i+1}(3,4)==0
                        set(AuxWire(j,1),'Visible','off');
                    else
                        x=[ModelMarkerCenter{j,i+1}(1,1) T_MeasuredMarkers{j,i+1}(1,4)];
                        y=[ModelMarkerCenter{j,i+1}(2,1) T_MeasuredMarkers{j,i+1}(2,4)];
                        z=[ModelMarkerCenter{j,i+1}(3,1) T_MeasuredMarkers{j,i+1}(3,4)];
                        set(AuxWire(j,1),'XData',x,'YData',y,'ZData',z);
                        if strcmp(AuxWire(j,1).Visible,'off')==1
                            set(AuxWire(j,1),'Visible','on');
                        end
                    end
                end
                for j = 1:nSegments
                    T = T_Segments{j,(i+1)};
                    set(MODS{j,1},'Matrix',T);
                    
                    set(JNT{j,1},'Matrix',T);
                    
                    T = T_Segments{j,(i+1)};    %Obtain the transformation matrix T from the .MAT file
                    set(H{j,1},'Matrix',T);  % Update the object
                    set(H{j,2},'Matrix',T);    % Update the object's LCS
                    app.TimeTextArea.Value = num2str(Time{1,(i+1)});
                    app.FrameEditField.Value=frame; %Print frame number in frame box
                    app.FrameSlider.Value=frame; %Print frame number in frame box
                end
            catch ME
                return;
            end
        end

        % Value changed function: ShowHideallLCSCheckBox
        function ShowHideallLCSCheckBoxValueChanged(app, event)
            try
                %Declare global variables
                global H
                global nSegments
                
                value = app.ShowHideallLCSCheckBox.Value;
                for i=1:nSegments
                    if ~isempty(H{i,2})
                        if value == true
                            set(H{i,2},'Visible','on');
                        elseif value == false
                            set(H{i,2},'Visible','off');
                        end
                    end
                end
            catch ME
                return;
            end
        end

        % Value changed function: ShowHideGCSCheckBox
        function ShowHideGCSCheckBoxValueChanged(app, event)
            try
                %Declare global variables
                global GCS
                value = app.ShowHideGCSCheckBox.Value;
                if value==true
                    set(GCS,'Visible','on');
                elseif value==false
                    set(GCS,'Visible','off');
                end
            catch ME
                return;
            end
        end

        % Value changed function: ShowHideSegmentLCSListBox
        function ShowHideSegmentLCSListBoxValueChanged(app, event)
            try
                value = app.ShowHideSegmentLCSListBox.Value;
                global H
                global nSegments
                global GCS
                
                for i = 1:nSegments
                    if value==H{i,3}
                        if strcmp(value,'Ground')==1
                            if strcmp(GCS.Visible,'off')==1
                                set(GCS,'Visible','on');
                                break;
                            elseif strcmp(GCS.Visible,'on')==1
                                set(GCS,'Visible','off');
                                break;
                            end
                        end
                        if strcmp(H{i,2}.Visible,'off')==1
                            set(H{i,2},'Visible','on');
                            break;
                        elseif strcmp(H{i,2}.Visible,'on')==1
                            set(H{i,2},'Visible','off');
                            break;
                        end
                    end
                end
                app.ShowHideSegmentLCSListBox.Value={};
            catch ME
                return;
            end
        end

        % Value changed function: ShowHideMarkerNamesCheckBox
        function ShowHideMarkerNamesCheckBoxValueChanged(app, event)
            try
                value = app.ShowHideMarkerNamesCheckBox.Value;
                global nSegments nMarkers
                global ModelMarkersSphere
                
                for i=1:nSegments
                    if nMarkers(i,1)>0
                        for j=1:nMarkers(i,1)
                            if value==true
                                set(ModelMarkersSphere{i,1}(j,2),'Visible','on');
                            elseif value==false
                                set(ModelMarkersSphere{i,1}(j,2),'Visible','off');
                            end
                        end
                    end
                end
            catch ME
                return;
            end
        end

        % Button pushed function: PlayForwardButton
        function PlayForwardButtonPushed(app, event)
            try
                global PlayForward
                global PlayBackward
                global PauseAnimation
                PlayForward = 1;
                PlayBackward = 0;
                app.PauseButton.Value=false;
                PauseAnimation = 0;
            catch ME
                return;
            end
        end

        % Button pushed function: PlayBackwardButton
        function PlayBackwardButtonPushed(app, event)
            try
                global PlayBackward
                global PlayForward
                global PauseAnimation
                PlayForward = 0;
                PlayBackward = 1;
                app.PauseButton.Value=false;
                PauseAnimation = 0;
            catch ME
                return;
            end
        end

        % Value changed function: PauseButton
        function PauseButtonValueChanged(app, event)
            try
                global PauseAnimation
                global PlayForward PlayBackward
                global FrameForward FrameBackward
                app.PauseButton.Value=true;
                PauseAnimation=1;
                PlayForward=0;
                PlayBackward=0;
                FrameForward=0;
                FrameBackward=0;
            catch ME
                return;
            end
        end

        % Button pushed function: FrameForwardButton
        function FrameForwardButtonPushed(app, event)
            try
                global FrameForward
                global PlayForward PlayBackward
                if PlayForward==0 && PlayBackward==0
                    FrameForward = 1;
                end
            catch ME
                return;
            end
        end

        % Button pushed function: FrameBackwardButton
        function FrameBackwardButtonPushed(app, event)
            try
                global FrameBackward
                global PlayForward PlayBackward
                if PlayForward==0 && PlayBackward==0
                    FrameBackward = 1;
                end
            catch ME
                return;
            end
        end

        % Value changed function: ShowHideSegmentsListBox
        function ShowHideSegmentsListBoxValueChanged(app, event)
            try
                value = app.ShowHideSegmentsListBox.Value;
                global H
                global nSegments
                
                for i = 1:nSegments
                    if value==H{i,3}
                        if ~isempty(H{i,1})
                            if strcmp(H{i,1}.Visible,'off')==1
                                set(H{i,1},'Visible','on');
                                break;
                            elseif strcmp(H{i,1}.Visible,'on')==1
                                set(H{i,1},'Visible','off');
                                break;
                            end
                        else
                            break;
                        end
                    end
                end
                app.ShowHideSegmentsListBox.Value={};
            catch ME
                return;
            end
        end

        % Value changed function: ShowHideSegmentsCheckBox
        function ShowHideSegmentsCheckBoxValueChanged(app, event)
            try
                value = app.ShowHideSegmentsCheckBox.Value;
                global H
                global nSegments
                
                for i=1:nSegments
                    if ~isempty(H{i,1})
                        if value == true
                            set(H{i,1},'Visible','on');
                        elseif value == false
                            set(H{i,1},'Visible','off');
                        end
                    end
                end
            catch ME
                return;
            end
        end

        % Value changed function: ShowHideModelMarkersCheckBox
        function ShowHideModelMarkersCheckBoxValueChanged(app, event)
            try
                value = app.ShowHideModelMarkersCheckBox.Value;
                global MODS
                global nSegments
                
                for i=1:nSegments
                    if ~isempty(MODS{i,1})
                        if value == true
                            set(MODS{i,1},'Visible','on');
                        elseif value == false
                            set(MODS{i,1},'Visible','off');
                        end
                    end
                end
            catch ME
                return;
            end
        end

        % Value changed function: 
        % ShowHideExperimentalMarkersCheckBox
        function ShowHideExperimentalMarkersCheckBoxValueChanged(app, event)
            try
                value = app.ShowHideExperimentalMarkersCheckBox.Value;
                global MEDS
                global markershided
                nMeasuredMarkers=size(MEDS,1);
                
                for i=1:nMeasuredMarkers
                    if ~isempty(MEDS{i,1})
                        if value == true
                            set(MEDS{i,1},'Visible','on');
                            markershided=0;
                        elseif value == false
                            set(MEDS{i,1},'Visible','off');
                            markershided=1;
                        end
                    end
                end
            catch ME
                return;
            end
        end

        % Value changed function: SetSceneRotationOriginDropDown
        function SetSceneRotationOriginDropDownValueChanged(app, event)
            try
                value = app.SetSceneRotationOriginDropDown.Value;
                global appax
                global H
                global nSegments
                global T_Segments
                global frame
                nSamples=size(T_Segments,2);
                if frame>nSamples
                    sample=nSamples;
                elseif frame<1
                    sample=1;
                else
                    sample=frame;
                end
                
                for j = 1:nSegments
                    if value==H{j,3}
                        d=(T_Segments{j,sample}(1:3,4))';
                        appax.CameraTarget=d;
                        break;
                        %                     [X,Y,Z] = drawsphere(0.05,d(1,1),d(1,2),d(1,3));
                        %                     targ=surface(X,Y,Z,'EdgeColor','none','FaceColor','r');
                    end
                end
            catch ME
                return;
            end
        end

        % Value changed function: ShowHideJointsCheckBox
        function ShowHideJointsCheckBoxValueChanged(app, event)
            try
                value = app.ShowHideJointsCheckBox.Value;
                global JNT
                global nSegments
                
                for i=1:nSegments
                    if ~isempty(JNT{i,1})
                        if value == true
                            set(JNT{i,1},'Visible','on');
                        elseif value == false
                            set(JNT{i,1},'Visible','off');
                        end
                    end
                end
            catch ME
                return;
            end
        end

        % Value changed function: XLimminEditField
        function XLimminEditFieldValueChanged(app, event)
            try
                global appax
                value=round(app.XLimminEditField.Value,2);
                if value<app.XLimmaxEditField.Value
                    app.XLimminEditField.Value=value;
                    actualLims=appax.XLim;
                    actualLims(1,1)=value;
                    appax.XLim=actualLims;
                else
                    msgbox('Error: minimum X limit must be lower than maximum','Warning');
                    actualLims=appax.XLim;
                    app.XLimminEditField.Value=round(actualLims(1,1),2);
                end
            catch ME
                return;
            end
        end

        % Value changed function: XLimmaxEditField
        function XLimmaxEditFieldValueChanged(app, event)
            try
                global appax
                value=round(app.XLimmaxEditField.Value,2);
                if value>app.XLimminEditField.Value
                    app.XLimmaxEditField.Value=value;
                    actualLims=appax.XLim;
                    actualLims(1,2)=value;
                    appax.XLim=actualLims;
                else
                    msgbox('Error: maximum X limit must be greater than minimum','Warning');
                    actualLims=appax.XLim;
                    app.XLimmaxEditField.Value=round(actualLims(1,2),2);
                end
            catch ME
                return;
            end
        end

        % Value changed function: YLimminEditField
        function YLimminEditFieldValueChanged(app, event)
            try
                global appax
                value=round(app.YLimminEditField.Value,2);
                if value<app.YLimmaxEditField.Value
                    app.YLimminEditField.Value=value;
                    actualLims=appax.YLim;
                    actualLims(1,1)=value;
                    appax.YLim=actualLims;
                else
                    msgbox('Error: minimum Y limit must be lower than maximum','Warning');
                    actualLims=appax.YLim;
                    app.YLimminEditField.Value=round(actualLims(1,1),2);
                end
            catch ME
                return;
            end
        end

        % Value changed function: YLimmaxEditField
        function YLimmaxEditFieldValueChanged(app, event)
            try
                global appax
                value=round(app.YLimmaxEditField.Value,2);
                if value>app.YLimminEditField.Value
                    app.YLimmaxEditField.Value=value;
                    actualLims=appax.YLim;
                    actualLims(1,2)=value;
                    appax.YLim=actualLims;
                else
                    msgbox('Error: maximum Y limit must be greater than minimum','Warning');
                    actualLims=appax.YLim;
                    app.YLimmaxEditField.Value=round(actualLims(1,2),2);
                end
            catch ME
                return;
            end
        end

        % Value changed function: ZLimminEditField
        function ZLimminEditFieldValueChanged(app, event)
            try
                global appax
                value=round(app.ZLimminEditField.Value,2);
                if value<app.ZLimmaxEditField.Value
                    app.ZLimminEditField.Value=value;
                    actualLims=appax.ZLim;
                    actualLims(1,1)=value;
                    appax.ZLim=actualLims;
                else
                    msgbox('Error: minimum Z limit must be lower than maximum','Warning');
                    actualLims=appax.ZLim;
                    app.ZLimminEditField.Value=round(actualLims(1,1),2);
                end
            catch ME
                return;
            end
        end

        % Value changed function: ZLimmaxEditField
        function ZLimmaxEditFieldValueChanged(app, event)
            try
                global appax
                value=round(app.ZLimmaxEditField.Value,2);
                if value>app.ZLimminEditField.Value
                    app.ZLimmaxEditField.Value=value;
                    actualLims=appax.ZLim;
                    actualLims(1,2)=value;
                    appax.ZLim=actualLims;
                else
                    msgbox('Error: maximum Z limit must be greater than minimum','Warning');
                    actualLims=appax.ZLim;
                    app.ZLimmaxEditField.Value=round(actualLims(1,2),2);
                end
            catch ME
                return;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MotViewUIFigure = uifigure('Visible', 'off');
            app.MotViewUIFigure.Name = 'MotView';
            app.LoadAnimationButton = uibutton(app.MotViewUIFigure, 'push');
            app.LoadAnimationButton.ButtonPushedFcn = createCallbackFcn(app, @LoadAnimationButtonPushed, true);
            app.LoadAnimationButton.Text = 'Load Animation';
            app.Lamp = uilamp(app.MotViewUIFigure);
            app.PauseButton = uibutton(app.MotViewUIFigure, 'state');
            app.PauseButton.ValueChangedFcn = createCallbackFcn(app, @PauseButtonValueChanged, true);
            app.PauseButton.Text = '||';
            app.PlayForwardButton = uibutton(app.MotViewUIFigure, 'push');
            app.PlayForwardButton.ButtonPushedFcn = createCallbackFcn(app, @PlayForwardButtonPushed, true);
            app.PlayForwardButton.Text = '>';
            app.FrameForwardButton = uibutton(app.MotViewUIFigure, 'push');
            app.FrameForwardButton.ButtonPushedFcn = createCallbackFcn(app, @FrameForwardButtonPushed, true);
            app.FrameForwardButton.Tooltip = {'One frame forward'};
            app.FrameForwardButton.Text = '|>';
            app.PlayBackwardButton = uibutton(app.MotViewUIFigure, 'push');
            app.PlayBackwardButton.ButtonPushedFcn = createCallbackFcn(app, @PlayBackwardButtonPushed, true);
            app.PlayBackwardButton.Text = '<';
            app.FrameBackwardButton = uibutton(app.MotViewUIFigure, 'push');
            app.FrameBackwardButton.ButtonPushedFcn = createCallbackFcn(app, @FrameBackwardButtonPushed, true);
            app.FrameBackwardButton.Tooltip = {'One frame backward'};
            app.FrameBackwardButton.Text = '<|';
            app.FrameSlider = uislider(app.MotViewUIFigure);
            app.FrameSlider.Limits = [0 280];
            app.FrameSlider.ValueChangingFcn = createCallbackFcn(app, @FrameSliderValueChanging, true);
            app.FrameEditFieldLabel = uilabel(app.MotViewUIFigure);
            app.FrameEditFieldLabel.Text = 'Frame';
            app.FrameEditField = uieditfield(app.MotViewUIFigure, 'numeric');
            app.TimeTextAreaLabel = uilabel(app.MotViewUIFigure);
            app.TimeTextAreaLabel.Text = 'Time';
            app.TimeTextArea = uitextarea(app.MotViewUIFigure);
            app.TimeTextArea.Editable = 'off';
            app.TimeTextArea.Value = {'0'};
            app.CLOSEANIMATIONButton = uibutton(app.MotViewUIFigure, 'push');
            app.CLOSEANIMATIONButton.ButtonPushedFcn = createCallbackFcn(app, @CLOSEANIMATIONButtonPushed, true);
            app.CLOSEANIMATIONButton.Text = 'CLOSE ANIMATION';
            app.DraweveryframesEditFieldLabel = uilabel(app.MotViewUIFigure);
            app.DraweveryframesEditFieldLabel.Text = 'Draw every                frames';
            app.DraweveryframesEditField = uieditfield(app.MotViewUIFigure, 'numeric');
            app.DraweveryframesEditField.Limits = [1 Inf];
            app.DraweveryframesEditField.Value = 5;
            app.ShowHideGCSCheckBox = uicheckbox(app.MotViewUIFigure);
            app.ShowHideGCSCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowHideGCSCheckBoxValueChanged, true);
            app.ShowHideGCSCheckBox.Text = 'Show/Hide GCS';
            app.ShowHideGCSCheckBox.Value = true;
            app.ShowHideallLCSCheckBox = uicheckbox(app.MotViewUIFigure);
            app.ShowHideallLCSCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowHideallLCSCheckBoxValueChanged, true);
            app.ShowHideallLCSCheckBox.Text = 'Show/Hide all LCS';
            app.ShowHideallLCSCheckBox.Value = true;
            app.ShowHideSegmentsCheckBox = uicheckbox(app.MotViewUIFigure);
            app.ShowHideSegmentsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowHideSegmentsCheckBoxValueChanged, true);
            app.ShowHideSegmentsCheckBox.Text = 'Show/Hide Segments';
            app.ShowHideSegmentsCheckBox.Value = true;
            app.ShowHideModelMarkersCheckBox = uicheckbox(app.MotViewUIFigure);
            app.ShowHideModelMarkersCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowHideModelMarkersCheckBoxValueChanged, true);
            app.ShowHideModelMarkersCheckBox.Text = 'Show/Hide Model Markers';
            app.ShowHideModelMarkersCheckBox.Value = true;
            app.ShowHideExperimentalMarkersCheckBox = uicheckbox(app.MotViewUIFigure);
            app.ShowHideExperimentalMarkersCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowHideExperimentalMarkersCheckBoxValueChanged, true);
            app.ShowHideExperimentalMarkersCheckBox.Text = 'Show/Hide Experimental Markers';
            app.ShowHideExperimentalMarkersCheckBox.Value = true;
            app.ShowHideMarkerNamesCheckBox = uicheckbox(app.MotViewUIFigure);
            app.ShowHideMarkerNamesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowHideMarkerNamesCheckBoxValueChanged, true);
            app.ShowHideMarkerNamesCheckBox.Text = 'Show/Hide Marker Names';
            app.ShowHideMarkerNamesCheckBox.Value = true;
            app.ShowHideSegmentLCSListBoxLabel = uilabel(app.MotViewUIFigure);
            app.ShowHideSegmentLCSListBoxLabel.Text = {'Show/Hide'; 'Segment LCS'};
            app.ShowHideSegmentLCSListBox = uilistbox(app.MotViewUIFigure);
            app.ShowHideSegmentLCSListBox.ValueChangedFcn = createCallbackFcn(app, @ShowHideSegmentLCSListBoxValueChanged, true);
            app.ShowHideSegmentLCSListBox.Value = {};
            app.ShowHideSegmentLabel = uilabel(app.MotViewUIFigure);
            app.ShowHideSegmentLabel.Text = {'Show/Hide'; 'Segment'};
            app.ShowHideSegmentsListBox = uilistbox(app.MotViewUIFigure);
            app.ShowHideSegmentsListBox.ValueChangedFcn = createCallbackFcn(app, @ShowHideSegmentsListBoxValueChanged, true);
            app.ShowHideSegmentsListBox.Value = {};
            app.SetSceneRotationOriginLabel = uilabel(app.MotViewUIFigure);
            app.SetSceneRotationOriginLabel.Text = {'Set Scene '; 'Rotation Origin'};
            app.SetSceneRotationOriginDropDown = uidropdown(app.MotViewUIFigure);
            app.SetSceneRotationOriginDropDown.ValueChangedFcn = createCallbackFcn(app, @SetSceneRotationOriginDropDownValueChanged, true);
            app.ShowHideJointsCheckBox = uicheckbox(app.MotViewUIFigure);
            app.ShowHideJointsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowHideJointsCheckBoxValueChanged, true);
            app.ShowHideJointsCheckBox.Text = 'Show/Hide Joints';
            app.ShowHideJointsCheckBox.Value = true;
            app.XLimminEditFieldLabel = uilabel(app.MotViewUIFigure);
            app.XLimminEditFieldLabel.Text = 'XLim min';
            app.XLimminEditField = uieditfield(app.MotViewUIFigure, 'numeric');
            app.XLimminEditField.ValueChangedFcn = createCallbackFcn(app, @XLimminEditFieldValueChanged, true);
            app.YLimminEditFieldLabel = uilabel(app.MotViewUIFigure);
            app.YLimminEditFieldLabel.Text = 'YLim min';
            app.YLimminEditField = uieditfield(app.MotViewUIFigure, 'numeric');
            app.YLimminEditField.ValueChangedFcn = createCallbackFcn(app, @YLimminEditFieldValueChanged, true);
            app.ZLimminEditFieldLabel = uilabel(app.MotViewUIFigure);
            app.ZLimminEditFieldLabel.Text = 'ZLim min';
            app.ZLimminEditField = uieditfield(app.MotViewUIFigure, 'numeric');
            app.ZLimminEditField.ValueChangedFcn = createCallbackFcn(app, @ZLimminEditFieldValueChanged, true);
            app.XLimmaxEditField_4Label = uilabel(app.MotViewUIFigure);
            app.XLimmaxEditField_4Label.Text = 'XLim max';
            app.XLimmaxEditField = uieditfield(app.MotViewUIFigure, 'numeric');
            app.XLimmaxEditField.ValueChangedFcn = createCallbackFcn(app, @XLimmaxEditFieldValueChanged, true);
            app.YLimmaxEditFieldLabel = uilabel(app.MotViewUIFigure);
            app.YLimmaxEditFieldLabel.Text = 'YLim max';
            app.YLimmaxEditField = uieditfield(app.MotViewUIFigure, 'numeric');
            app.YLimmaxEditField.ValueChangedFcn = createCallbackFcn(app, @YLimmaxEditFieldValueChanged, true);
            app.ZLimmaxEditFieldLabel = uilabel(app.MotViewUIFigure);
            app.ZLimmaxEditFieldLabel.Text = 'ZLim max';
            app.ZLimmaxEditField = uieditfield(app.MotViewUIFigure, 'numeric');
            app.ZLimmaxEditField.ValueChangedFcn = createCallbackFcn(app, @ZLimmaxEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.MotViewUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MotView

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MotViewUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MotViewUIFigure)
        end
    end
end